classdef BNP_Track_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        MCMCPanel                     matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        ExportMAPtracksButton         matlab.ui.control.Button
        BatchsizeEditField            matlab.ui.control.NumericEditField
        BatchsizeEditFieldLabel       matlab.ui.control.Label
        ProgressreportLabel           matlab.ui.control.Label
        StatusbarCheckBox             matlab.ui.control.CheckBox
        VisualCheckBox                matlab.ui.control.CheckBox
        ExportMCMCchainButton         matlab.ui.control.Button
        ExpandMCMCchainButton         matlab.ui.control.Button
        CreateMCMCchainButton         matlab.ui.control.Button
        DataPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        NumericalapertureEditFieldLabel  matlab.ui.control.Label
        EmissionwavelengthEditFieldLabel  matlab.ui.control.Label
        PixelsizeEditFieldLabel       matlab.ui.control.Label
        DeadperiodEditFieldLabel      matlab.ui.control.Label
        ExposureperiodEditFieldLabel  matlab.ui.control.Label
        OverallgainEditFieldLabel     matlab.ui.control.Label
        PixelvarianceEditFieldLabel   matlab.ui.control.Label
        PixeloffsetEditFieldLabel     matlab.ui.control.Label
        ImportimagedataButton         matlab.ui.control.Button
        ImmersionfluidDropDown        matlab.ui.control.DropDown
        NumericalapertureEditField    matlab.ui.control.NumericEditField
        EmissionwavelengthEditField   matlab.ui.control.NumericEditField
        PixelsizeEditField            matlab.ui.control.NumericEditField
        DeadperiodEditField           matlab.ui.control.NumericEditField
        ExposureperiodEditField       matlab.ui.control.NumericEditField
        ExcessnoiseDropDown           matlab.ui.control.DropDown
        OverallgainEditField          matlab.ui.control.NumericEditField
        PixelvarianceEditField        matlab.ui.control.NumericEditField
        PixeloffsetEditField          matlab.ui.control.NumericEditField
        LengthunitsEditField          matlab.ui.control.EditField
        TimeunitsEditField            matlab.ui.control.EditField
        ImageunitsEditField           matlab.ui.control.EditField
        ExcessnoiseDropDownLabel      matlab.ui.control.Label
        TimeunitsEditFieldLabel       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_1                       matlab.ui.control.Label
        CamerasettingsLabel           matlab.ui.control.Label
        TimesettingsLabel             matlab.ui.control.Label
        OpticsLabel                   matlab.ui.control.Label
        LengthunitsEditFieldLabel     matlab.ui.control.Label
        ImageunitsEditFieldLabel      matlab.ui.control.Label
        ImmersionfluidDropDownLabel   matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        w
        x
        y
        chain
        T = gobjects();
    end
    
    methods (Access = private)
        
        function update_labels(app)
            app.Label_1.Text = app.ImageunitsEditField.Value;
            app.Label_2.Text = [app.ImageunitsEditField.Value,'^2'];
            app.Label_3.Text = app.ImageunitsEditField.Value;
            
            app.Label_5.Text = app.TimeunitsEditField.Value;
            app.Label_6.Text = app.TimeunitsEditField.Value;

            app.Label_7.Text = app.LengthunitsEditField.Value;
            app.Label_8.Text = app.LengthunitsEditField.Value;
        end
        
        function activate_import(app)
            if app.PixeloffsetEditField.Enable ...
            && app.ExposureperiodEditField.Enable ...
            && app.PixelsizeEditField.Enable ...
            && app.PixeloffsetEditField.Value > 0 ...
            && app.PixelvarianceEditField.Value > 0 ...
            && app.OverallgainEditField.Value > 0 ...
            && app.ExposureperiodEditField.Value > 0 ...
            && app.DeadperiodEditField.Value > 0 ...
            && app.PixelsizeEditField.Value > 0 ...
            && app.EmissionwavelengthEditField.Value > 0 ...
            && app.NumericalapertureEditField.Value > 0
                app.ImportimagedataButton.Enable = true;
            else
                app.ImportimagedataButton.Enable = false;
            end
        end
        
        function [t,X,Y,Z] = get_MAP_tracks(app)
            idx = find(app.chain.i>=floor(0.5*app.chain.i(end)));
            [~,j] = max(app.chain.L(idx,1));
            i = idx(j);
            X = reshape(app.chain.X(i,:),app.chain.params.N,[]);
            Y = reshape(app.chain.Y(i,:),app.chain.params.N,[]);
            Z = reshape(app.chain.Z(i,:),app.chain.params.N,[]);
            ind = find(app.chain.b(i,:));
            X = double( X(:,ind) );
            Y = double( Y(:,ind) );
            Z = double( Z(:,ind) );
            t = app.chain.params.t_mid;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportimagedataButton
        function ImportimagedataButtonPushed(app, event)
            [file,path] = uigetfile({'*.mat'},...
                          'Select image data');
            if ~isequal(file,0)
                app.w = importdata( fullfile(path,file) );
                app.x = ( 0:size(app.w,1) ) * app.PixelsizeEditField.Value;
                app.y = ( 0:size(app.w,2) ) * app.PixelsizeEditField.Value;
                app.x = app.x - 0.5*(app.x(1)+app.x(end));
                app.y = app.y - 0.5*(app.y(1)+app.y(end));

                W = mean(app.w,3);
                W_quant = quantile(W(:),[0.001 0.999]);
                W = max(min(W,W_quant(2)),W_quant(1));
                histogram2(app.UIAxes,'XBinEdges',app.x,...
                                      'YBinEdges',app.y, ...
                                      'BinCounts',W,'DisplayStyle','tile')
                app.UIAxes.XLabel.String = ['x (',app.LengthunitsEditField.Value,')'];
                app.UIAxes.YLabel.String = ['y (',app.LengthunitsEditField.Value,')'];

                c = colorbar(app.UIAxes);
                c.Label.String = ['Mean image value (',app.ImageunitsEditField.Value,')'];

                colormap(app.UIAxes,'bone');
                app.UIAxes.XColor = 'k';
                app.UIAxes.YColor = 'k';
                axis(app.UIAxes,'image');

                xticks(app.UIAxes,'auto')
                yticks(app.UIAxes,'auto')

                app.ImportimagedataButton.Enable = false;

                app.PixeloffsetEditField.Enable = false;
                app.PixelvarianceEditField.Enable = false;
                app.OverallgainEditField.Enable = false;
                app.ExcessnoiseDropDown.Enable = false;
                app.ExposureperiodEditField.Enable = false;
                app.DeadperiodEditField.Enable = false;
                app.PixelsizeEditField.Enable = false;
                app.EmissionwavelengthEditField.Enable = false;
                app.NumericalapertureEditField.Enable = false;
                app.ImmersionfluidDropDown.Enable = false;

                app.Label_1.Enable = false;
                app.Label_2.Enable = false;
                app.Label_3.Enable = false;
                app.Label_5.Enable = false;
                app.Label_6.Enable = false;
                app.Label_7.Enable = false;
                app.Label_8.Enable = false;
                
                app.ImageunitsEditField.Enable = false;

                app.CreateMCMCchainButton.Enable = true;
            end
        end

        % Value changed function: ImageunitsEditField
        function ImageunitsEditFieldValueChanged(app, event)
            app.PixeloffsetEditField.Enable = true;
            app.PixeloffsetEditFieldLabel.Enable = true;
            app.PixelvarianceEditField.Enable = true;
            app.PixelvarianceEditFieldLabel.Enable = true;
            app.OverallgainEditField.Enable = true;
            app.OverallgainEditFieldLabel.Enable = true;
            app.ImageunitsEditField.Enable = false;
            update_labels(app)
            activate_import(app)
        end

        % Value changed function: LengthunitsEditField
        function LengthunitsEditFieldValueChanged(app, event)
            app.PixelsizeEditField.Enable = true;
            app.PixelsizeEditFieldLabel.Enable = true;
            app.EmissionwavelengthEditField.Enable = true;
            app.EmissionwavelengthEditFieldLabel.Enable = true;
            app.NumericalapertureEditField.Enable = true;
            app.NumericalapertureEditFieldLabel.Enable = true;
            app.LengthunitsEditField.Enable = false;
            update_labels(app)
            activate_import(app)
        end

        % Value changed function: TimeunitsEditField
        function TimeunitsEditFieldValueChanged(app, event)
            app.ExposureperiodEditField.Enable = true;
            app.ExposureperiodEditFieldLabel.Enable = true;
            app.DeadperiodEditField.Enable = true;
            app.DeadperiodEditFieldLabel.Enable = true;
            app.TimeunitsEditField.Enable = false;
            update_labels(app)
            activate_import(app)
        end

        % Value changed function: PixeloffsetEditField
        function PixeloffsetEditFieldValueChanged(app, event)
            activate_import(app)
        end

        % Value changed function: PixelvarianceEditField
        function PixelvarianceEditFieldValueChanged(app, event)
            activate_import(app)            
        end

        % Value changed function: OverallgainEditField
        function OverallgainEditFieldValueChanged(app, event)
            activate_import(app)            
        end

        % Value changed function: ExposureperiodEditField
        function ExposureperiodEditFieldValueChanged(app, event)
            activate_import(app)            
        end

        % Value changed function: DeadperiodEditField
        function DeadperiodEditFieldValueChanged(app, event)
            activate_import(app)            
        end

        % Value changed function: PixelsizeEditField
        function PixelsizeEditFieldValueChanged(app, event)
            activate_import(app)            
        end

        % Value changed function: EmissionwavelengthEditField
        function EmissionwavelengthEditFieldValueChanged(app, event)
            activate_import(app)            
        end

        % Value changed function: NumericalapertureEditField
        function NumericalapertureEditFieldValueChanged(app, event)
            activate_import(app)            
        end

        % Button pushed function: CreateMCMCchainButton
        function CreateMCMCchainButtonPushed(app, event)

            opts.units.image  = app.ImageunitsEditField.Value;
            opts.units.time   = app.TimeunitsEditField.Value;
            opts.units.length = app.LengthunitsEditField.Value;

            opts.t_ded = app.DeadperiodEditField.Value;

            opts.t_exp = app.ExposureperiodEditField.Value*ones(size(app.w,3),1);
            opts.x_bnd = app.x;
            opts.y_bnd = app.y;

            opts.w_cnt = app.w;
            opts.wM    = app.PixeloffsetEditField.Value;
            opts.wV    = app.PixelvarianceEditField.Value;
            opts.wG    = app.OverallgainEditField.Value;
            if     strcmp(app.ExcessnoiseDropDown.Value,'EMCCD')
                opts.wF = 2;
            elseif strcmp(app.ExcessnoiseDropDown.Value,'sCMOS/CCD')
                opts.wF = 1;
            end
            
            opts.lambda = app.EmissionwavelengthEditField.Value;
            opts.nNA    = app.NumericalapertureEditField.Value;
            if     strcmp(app.ImmersionfluidDropDown.Value,'Oil (1.51)')
                opts.nRI = 1.51;
            elseif strcmp(app.ImmersionfluidDropDown.Value,'Water (1.33)')
                opts.nRI = 1.33;
            end

            app.chain = chainer_main([],0,opts,false,[]);
            
            app.CreateMCMCchainButton.Enable = false;
            app.ExpandMCMCchainButton.Enable = true;
            app.ProgressreportLabel.Enable = true;
            app.StatusbarCheckBox.Enable = true;
            app.VisualCheckBox.Enable = true;
            app.BatchsizeEditField.Enable = true;
            app.ExportMCMCchainButton.Enable = true;
            app.ExportMAPtracksButton.Enable = true;
        end

        % Button pushed function: ExpandMCMCchainButton
        function ExpandMCMCchainButtonPushed(app, event)
            app.ExpandMCMCchainButton.Enable = false;
            app.ExportMCMCchainButton.Enable = false;
            app.ExportMAPtracksButton.Enable = false;
            delete(app.T);
            drawnow;
            
            app.chain = chainer_main(app.chain,[],[],false,[] );
            app.chain = chainer_main(app.chain,app.BatchsizeEditField.Value,[],app.StatusbarCheckBox.Value,app.VisualCheckBox.Value);

            [~,X,Y,~] = get_MAP_tracks(app);
            app.T = line(app.UIAxes,X,Y,'color','g','marker','.','linewidth',2);

            app.ExpandMCMCchainButton.Enable = true;
            app.ExportMCMCchainButton.Enable = true;
            app.ExportMAPtracksButton.Enable = true;
        end

        % Button pushed function: ExportMCMCchainButton
        function ExportMCMCchainButtonPushed(app, event)
            [file,path] = uiputfile('*.mat','Save file','MCMC_chain.mat');
            if ~ ( isequal(file,0) || isequal(path,0) )
                chain = app.chain;
                save(fullfile(path,file),'chain')
            end
        end

        % Button pushed function: ExportMAPtracksButton
        function ExportMAPtracksButtonPushed(app, event)
            [file,path] = uiputfile('*.mat','Save file','MAP_tracks.mat');
            if ~ ( isequal(file,0) || isequal(path,0) )
                [t,X,Y,Z] = get_MAP_tracks(app);
                save(fullfile(path,file),'t','X','Y','Z')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.UIAxes = uiaxes(app.GridLayout4);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.DataPanel = uipanel(app.GridLayout);
            app.DataPanel.Title = 'Data';
            app.GridLayout2 = uigridlayout(app.DataPanel);
            app.ImmersionfluidDropDownLabel = uilabel(app.GridLayout2);
            app.ImmersionfluidDropDownLabel.Text = 'Immersion fluid';
            app.ImageunitsEditFieldLabel = uilabel(app.GridLayout2);
            app.ImageunitsEditFieldLabel.Text = 'Image units';
            app.LengthunitsEditFieldLabel = uilabel(app.GridLayout2);
            app.LengthunitsEditFieldLabel.Text = 'Length units';
            app.OpticsLabel = uilabel(app.GridLayout2);
            app.OpticsLabel.Text = 'Optics';
            app.TimesettingsLabel = uilabel(app.GridLayout2);
            app.TimesettingsLabel.Text = 'Time settings';
            app.CamerasettingsLabel = uilabel(app.GridLayout2);
            app.CamerasettingsLabel.Text = 'Camera settings';
            app.Label_1 = uilabel(app.GridLayout2);
            app.Label_1.Text = '';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '';
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.GridLayout2);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = '';
            app.TimeunitsEditFieldLabel = uilabel(app.GridLayout2);
            app.TimeunitsEditFieldLabel.Text = 'Time units';
            app.ExcessnoiseDropDownLabel = uilabel(app.GridLayout2);
            app.ExcessnoiseDropDownLabel.Text = 'Excess noise';
            app.ImageunitsEditField = uieditfield(app.GridLayout2, 'text');
            app.ImageunitsEditField.ValueChangedFcn = createCallbackFcn(app, @ImageunitsEditFieldValueChanged, true);
            app.TimeunitsEditField = uieditfield(app.GridLayout2, 'text');
            app.TimeunitsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeunitsEditFieldValueChanged, true);
            app.LengthunitsEditField = uieditfield(app.GridLayout2, 'text');
            app.LengthunitsEditField.ValueChangedFcn = createCallbackFcn(app, @LengthunitsEditFieldValueChanged, true);
            app.PixeloffsetEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PixeloffsetEditField.LowerLimitInclusive = 'off';
            app.PixeloffsetEditField.Limits = [0 Inf];
            app.PixeloffsetEditField.ValueChangedFcn = createCallbackFcn(app, @PixeloffsetEditFieldValueChanged, true);
            app.PixeloffsetEditField.Enable = 'off';
            app.PixeloffsetEditField.Value = 1;
            app.PixelvarianceEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PixelvarianceEditField.LowerLimitInclusive = 'off';
            app.PixelvarianceEditField.Limits = [0 Inf];
            app.PixelvarianceEditField.ValueChangedFcn = createCallbackFcn(app, @PixelvarianceEditFieldValueChanged, true);
            app.PixelvarianceEditField.Enable = 'off';
            app.PixelvarianceEditField.Value = 1;
            app.OverallgainEditField = uieditfield(app.GridLayout2, 'numeric');
            app.OverallgainEditField.LowerLimitInclusive = 'off';
            app.OverallgainEditField.Limits = [0 Inf];
            app.OverallgainEditField.ValueChangedFcn = createCallbackFcn(app, @OverallgainEditFieldValueChanged, true);
            app.OverallgainEditField.Enable = 'off';
            app.OverallgainEditField.Value = 1;
            app.ExcessnoiseDropDown = uidropdown(app.GridLayout2);
            app.ExcessnoiseDropDown.Items = {'EMCCD', 'sCMOS/CCD'};
            app.ExcessnoiseDropDown.Value = 'EMCCD';
            app.ExposureperiodEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ExposureperiodEditField.LowerLimitInclusive = 'off';
            app.ExposureperiodEditField.Limits = [0 Inf];
            app.ExposureperiodEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureperiodEditFieldValueChanged, true);
            app.ExposureperiodEditField.Enable = 'off';
            app.ExposureperiodEditField.Value = 1;
            app.DeadperiodEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DeadperiodEditField.LowerLimitInclusive = 'off';
            app.DeadperiodEditField.Limits = [0 Inf];
            app.DeadperiodEditField.ValueChangedFcn = createCallbackFcn(app, @DeadperiodEditFieldValueChanged, true);
            app.DeadperiodEditField.Enable = 'off';
            app.DeadperiodEditField.Value = 1;
            app.PixelsizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PixelsizeEditField.LowerLimitInclusive = 'off';
            app.PixelsizeEditField.Limits = [0 Inf];
            app.PixelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizeEditFieldValueChanged, true);
            app.PixelsizeEditField.Enable = 'off';
            app.PixelsizeEditField.Value = 1;
            app.EmissionwavelengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.EmissionwavelengthEditField.LowerLimitInclusive = 'off';
            app.EmissionwavelengthEditField.Limits = [0 Inf];
            app.EmissionwavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @EmissionwavelengthEditFieldValueChanged, true);
            app.EmissionwavelengthEditField.Enable = 'off';
            app.EmissionwavelengthEditField.Value = 1;
            app.NumericalapertureEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumericalapertureEditField.LowerLimitInclusive = 'off';
            app.NumericalapertureEditField.Limits = [0 Inf];
            app.NumericalapertureEditField.ValueChangedFcn = createCallbackFcn(app, @NumericalapertureEditFieldValueChanged, true);
            app.NumericalapertureEditField.Enable = 'off';
            app.NumericalapertureEditField.Value = 1;
            app.ImmersionfluidDropDown = uidropdown(app.GridLayout2);
            app.ImmersionfluidDropDown.Items = {'Oil (1.51)', 'Water (1.33)'};
            app.ImmersionfluidDropDown.Value = 'Oil (1.51)';
            app.ImportimagedataButton = uibutton(app.GridLayout2, 'push');
            app.ImportimagedataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportimagedataButtonPushed, true);
            app.ImportimagedataButton.Enable = 'off';
            app.ImportimagedataButton.Text = 'Import image data';
            app.PixeloffsetEditFieldLabel = uilabel(app.GridLayout2);
            app.PixeloffsetEditFieldLabel.Enable = 'off';
            app.PixeloffsetEditFieldLabel.Text = 'Pixel offset';
            app.PixelvarianceEditFieldLabel = uilabel(app.GridLayout2);
            app.PixelvarianceEditFieldLabel.Enable = 'off';
            app.PixelvarianceEditFieldLabel.Text = 'Pixel variance';
            app.OverallgainEditFieldLabel = uilabel(app.GridLayout2);
            app.OverallgainEditFieldLabel.Enable = 'off';
            app.OverallgainEditFieldLabel.Text = 'Overall gain';
            app.ExposureperiodEditFieldLabel = uilabel(app.GridLayout2);
            app.ExposureperiodEditFieldLabel.Enable = 'off';
            app.ExposureperiodEditFieldLabel.Text = 'Exposure period';
            app.DeadperiodEditFieldLabel = uilabel(app.GridLayout2);
            app.DeadperiodEditFieldLabel.Enable = 'off';
            app.DeadperiodEditFieldLabel.Text = 'Dead period';
            app.PixelsizeEditFieldLabel = uilabel(app.GridLayout2);
            app.PixelsizeEditFieldLabel.Enable = 'off';
            app.PixelsizeEditFieldLabel.Text = 'Pixel size';
            app.EmissionwavelengthEditFieldLabel = uilabel(app.GridLayout2);
            app.EmissionwavelengthEditFieldLabel.Enable = 'off';
            app.EmissionwavelengthEditFieldLabel.Text = 'Emission wavelength';
            app.NumericalapertureEditFieldLabel = uilabel(app.GridLayout2);
            app.NumericalapertureEditFieldLabel.Enable = 'off';
            app.NumericalapertureEditFieldLabel.Text = 'Numerical aperture';
            app.MCMCPanel = uipanel(app.GridLayout);
            app.MCMCPanel.Title = 'MCMC';
            app.GridLayout3 = uigridlayout(app.MCMCPanel);
            app.CreateMCMCchainButton = uibutton(app.GridLayout3, 'push');
            app.CreateMCMCchainButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMCMCchainButtonPushed, true);
            app.CreateMCMCchainButton.Enable = 'off';
            app.CreateMCMCchainButton.Text = 'Create MCMC chain';
            app.ExpandMCMCchainButton = uibutton(app.GridLayout3, 'push');
            app.ExpandMCMCchainButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandMCMCchainButtonPushed, true);
            app.ExpandMCMCchainButton.Enable = 'off';
            app.ExpandMCMCchainButton.Text = 'Expand MCMC chain';
            app.ExportMCMCchainButton = uibutton(app.GridLayout3, 'push');
            app.ExportMCMCchainButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMCMCchainButtonPushed, true);
            app.ExportMCMCchainButton.Enable = 'off';
            app.ExportMCMCchainButton.Text = 'Export MCMC chain';
            app.VisualCheckBox = uicheckbox(app.GridLayout3);
            app.VisualCheckBox.Enable = 'off';
            app.VisualCheckBox.Text = 'Visual';
            app.StatusbarCheckBox = uicheckbox(app.GridLayout3);
            app.StatusbarCheckBox.Enable = 'off';
            app.StatusbarCheckBox.Text = 'Status bar';
            app.StatusbarCheckBox.Value = true;
            app.ProgressreportLabel = uilabel(app.GridLayout3);
            app.ProgressreportLabel.Enable = 'off';
            app.ProgressreportLabel.Text = 'Progress report';
            app.BatchsizeEditFieldLabel = uilabel(app.GridLayout3);
            app.BatchsizeEditFieldLabel.Enable = 'off';
            app.BatchsizeEditFieldLabel.Text = 'Batch size';
            app.BatchsizeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.BatchsizeEditField.LowerLimitInclusive = 'off';
            app.BatchsizeEditField.Limits = [0 Inf];
            app.BatchsizeEditField.RoundFractionalValues = 'on';
            app.BatchsizeEditField.Enable = 'off';
            app.BatchsizeEditField.Value = 500;
            app.ExportMAPtracksButton = uibutton(app.GridLayout3, 'push');
            app.ExportMAPtracksButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMAPtracksButtonPushed, true);
            app.ExportMAPtracksButton.Enable = 'off';
            app.ExportMAPtracksButton.Text = 'Export MAP tracks';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BNP_Track_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end